classdef Lab2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GraphPanel                  matlab.ui.container.Panel
        Right                       matlab.ui.control.UIAxes
        Left                        matlab.ui.control.UIAxes
        CameraSettingsPanel         matlab.ui.container.Panel
        CamRows_EF                  matlab.ui.control.NumericEditField
        CamRows_Slider              matlab.ui.control.Slider
        CamCol_EF                   matlab.ui.control.NumericEditField
        xmLabel_14                  matlab.ui.control.Label
        CamCol_Slider               matlab.ui.control.Slider
        baseline_EF                 matlab.ui.control.NumericEditField
        xmLabel_13                  matlab.ui.control.Label
        baseline_Slider             matlab.ui.control.Slider
        pixelSize_EF                matlab.ui.control.NumericEditField
        xmLabel_12                  matlab.ui.control.Label
        pixelSize_Slider            matlab.ui.control.Slider
        focalLength_EF              matlab.ui.control.NumericEditField
        xmLabel_11                  matlab.ui.control.Label
        focalLength_Slider          matlab.ui.control.Slider
        X                           matlab.ui.control.Label
        Panel2                      matlab.ui.container.Panel
        ZmCalcEditField             matlab.ui.control.NumericEditField
        ZmEditFieldLabel            matlab.ui.control.Label
        YmCalcEditField             matlab.ui.control.NumericEditField
        YmEditFieldLabel            matlab.ui.control.Label
        XmCalcEditField             matlab.ui.control.NumericEditField
        XmEditFieldLabel            matlab.ui.control.Label
        ErrDistancemEditField       matlab.ui.control.NumericEditField
        ErrDistancemEditFieldLabel  matlab.ui.control.Label
        ZerrmEditField              matlab.ui.control.NumericEditField
        ZerrmEditFieldLabel         matlab.ui.control.Label
        YerrmEditField              matlab.ui.control.NumericEditField
        YerrmEditFieldLabel         matlab.ui.control.Label
        XerrmEditField              matlab.ui.control.NumericEditField
        XerrmEditFieldLabel         matlab.ui.control.Label
        DistanceSettings            matlab.ui.container.Panel
        zEditField                  matlab.ui.control.NumericEditField
        xmLabel_10                  matlab.ui.control.Label
        zSlider                     matlab.ui.control.Slider
        yEditField                  matlab.ui.control.NumericEditField
        xmLabel_9                   matlab.ui.control.Label
        ySlider                     matlab.ui.control.Slider
        xEditField                  matlab.ui.control.NumericEditField
        xmLabel_8                   matlab.ui.control.Label
        xSlider                     matlab.ui.control.Slider
        CalculateButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            app.xEditField.Value = app.xSlider.Value;
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.xSlider.Value = app.xEditField.Value;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.ySlider.Value = app.yEditField.Value;
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            app.yEditField.Value = app.ySlider.Value;
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            app.zEditField.Value = app.zSlider.Value;
            
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            app.zSlider.Value = app.zEditField.Value;
        end

        % Value changed function: focalLength_EF
        function focalLength_EFValueChanged(app, event)
            app.focalLength_Slider.Value = app.focalLength_EF.Value;
        end

        % Value changed function: focalLength_Slider
        function focalLength_SliderValueChanged(app, event)
            app.focalLength_EF.Value = app.focalLength_Slider.Value;
        end

        % Value changed function: baseline_Slider
        function baseline_SliderValueChanged(app, event)
            app.baseline_EF.Value = app.baseline_Slider.Value;
            
        end

        % Value changed function: baseline_EF
        function baseline_EFValueChanged(app, event)
            app.baseline_Slider.Value = app.baseline_EF.Value;
            
        end

        % Value changed function: pixelSize_EF
        function pixelSize_EFValueChanged(app, event)
            app.pixelSize_Slider.Value = app.pixelSize_EF.Value;
            
        end

        % Value changed function: pixelSize_Slider
        function pixelSize_SliderValueChanged(app, event)
            app.pixelSize_EF.Value = app.pixelSize_Slider.Value;
            
        end

        % Value changed function: CamCol_Slider
        function CamCol_SliderValueChanged(app, event)
            app.CamCol_EF.Value = app.CamCol_Slider.Value;
            
        end

        % Value changed function: CamCol_EF
        function CamCol_EFValueChanged(app, event)
            app.CamCol_Slider.Value = app.CamCol_EF.Value;
            
        end

        % Value changed function: CamRows_Slider
        function CamRows_SliderValueChanged(app, event)
            app.CamRows_EF.Value = app.CamRows_Slider.Value;
            
        end

        % Value changed function: CamRows_EF
        function CamRows_EFValueChanged(app, event)
            app.CamRows_Slider.Value = app.CamRows_EF.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
    Xtrue_m = app.xEditField.Value;
    Ytrue_m = app.yEditField.Value;
    Ztrue_m = app.zEditField.Value;

    f_mm   = app.focalLength_EF.Value;
    b_mm   = app.baseline_EF.Value;
    ps_um  = app.pixelSize_EF.Value;

    cols_px = app.CamCol_EF.Value;
    rows_px = app.CamRows_EF.Value;

    % Center %
    cx_px = cols_px/2;
    cy_px = rows_px/2;
    
    % Conversions %
    f_m  = f_mm / 1000;
    b_m  = b_mm / 1000;
    ps_m = ps_um * 1e-6;

    % Creating the Cameras %
    camL = CentralCamera('focal', f_m, 'pixel', ps_m, 'resolution', [cols_px rows_px], 'centre', [cx_px cy_px]);
    camR = CentralCamera('focal', f_m, 'pixel', ps_m, 'resolution', [cols_px rows_px], 'centre', [cx_px cy_px]);

    % Positioning Cameras %
    camL.T = SE3(0, 0, 0); % Camera Left a 0 %
    camR.T = SE3(b_m, 0, 0); % Camera Right at 50 mm %

    % Position Ball and Project
    P = [Xtrue_m; Ytrue_m; Ztrue_m];
    uvL = camL.project(P);
    uvR = camR.project(P);

    % Extract the Pixel Coordinates for Right and Left Camera %
    xL_px = uvL(1);  yL_px = uvL(2);
    xR_px = uvR(1);  yR_px = uvR(2);

    ps_mm = ps_um / 1000;

    % X,Y,Z Calculations %
    d_mm = abs((xL_px - cx_px) - (xR_px - cx_px)) * ps_mm;

    Z_mm = (b_mm * f_mm) / d_mm;
    Z_m  = Z_mm / 1000;

    X_m = Z_m * ((xL_px - cx_px) * ps_mm) / f_mm; 
    Y_m     = Z_m * ((yL_px - cy_px) * ps_mm) / f_mm;

    app.XmCalcEditField.Value = X_m;
    app.YmCalcEditField.Value = Y_m;
    app.ZmCalcEditField.Value = Z_m;

    % Error Calculations %
    Xerr_mm = (X_m     - Xtrue_m) * 100;
    Yerr_mm = (Y_m     - Ytrue_m) * 100;
    Zerr_mm = (Z_m     - Ztrue_m) * 100;

    app.XerrmEditField.Value = Xerr_mm;
    app.YerrmEditField.Value = Yerr_mm;
    app.ZerrmEditField.Value = Zerr_mm;

    app.ErrDistancemEditField.Value = sqrt(Xerr_mm^2 + Yerr_mm^2 + Zerr_mm^2);

    % Plot the stuff

ptSize  = 100;     % size of the ball marker
ptLineW = 1.5;

% Clear axes
cla(app.Left);
cla(app.Right);

% Lock axes to image resolution
app.Left.XLim  = [0 cols_px];
app.Left.YLim  = [0 rows_px];
app.Right.XLim = [0 cols_px];
app.Right.YLim = [0 rows_px];

% Make axes behave like image coordinates
set(app.Left,  'YDir', 'reverse');
set(app.Right, 'YDir', 'reverse');

% Optional: draw image boundary (helps visually)
hold(app.Left,'on');
rectangle(app.Left,'Position',[0 0 cols_px rows_px],'EdgeColor',[0.6 0.6 0.6]);
hold(app.Left,'off');

hold(app.Right,'on');
rectangle(app.Right,'Position',[0 0 cols_px rows_px],'EdgeColor',[0.6 0.6 0.6]);
hold(app.Right,'off');

% Check visibility in each image
inLeft  = (xL_px >= 0) && (xL_px <= cols_px) && ...
          (yL_px >= 0) && (yL_px <= rows_px);

inRight = (xR_px >= 0) && (xR_px <= cols_px) && ...
          (yR_px >= 0) && (yR_px <= rows_px);

% Plot left image
if inLeft
    hold(app.Left,'on');
    scatter(app.Left, xL_px, yL_px, ptSize, 'filled', 'LineWidth', ptLineW);
    hold(app.Left,'off');
else
    text(app.Left, cols_px*0.05, rows_px*0.1, ...
        'Ball off-screen', 'FontSize', 12);
end

% Plot right image
if inRight
    hold(app.Right,'on');
    scatter(app.Right, xR_px, yR_px, ptSize, 'filled', 'LineWidth', ptLineW);
    hold(app.Right,'off');
else
    text(app.Right, cols_px*0.05, rows_px*0.1, ...
        'Ball off-screen', 'FontSize', 12);
end

drawnow limitrate;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DistanceSettings = uipanel(app.UIFigure);
            app.DistanceSettings.Title = 'Panel';
            app.xSlider = uislider(app.DistanceSettings);
            app.xSlider.Limits = [-10 10];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xmLabel_8 = uilabel(app.DistanceSettings);
            app.xmLabel_8.Text = 'x(m)';
            app.xEditField = uieditfield(app.DistanceSettings, 'numeric');
            app.xEditField.Limits = [-10 10];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.ySlider = uislider(app.DistanceSettings);
            app.ySlider.Limits = [-10 10];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.xmLabel_9 = uilabel(app.DistanceSettings);
            app.xmLabel_9.Text = 'y(m)';
            app.yEditField = uieditfield(app.DistanceSettings, 'numeric');
            app.yEditField.Limits = [-10 10];
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zSlider = uislider(app.DistanceSettings);
            app.zSlider.Limits = [0 200];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.xmLabel_10 = uilabel(app.DistanceSettings);
            app.xmLabel_10.Text = 'z(m)';
            app.zEditField = uieditfield(app.DistanceSettings, 'numeric');
            app.zEditField.Limits = [0 200];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.XerrmEditFieldLabel = uilabel(app.Panel2);
            app.XerrmEditFieldLabel.Text = 'Xerr (m)';
            app.XerrmEditField = uieditfield(app.Panel2, 'numeric');
            app.XerrmEditField.Editable = 'off';
            app.YerrmEditFieldLabel = uilabel(app.Panel2);
            app.YerrmEditFieldLabel.Text = 'Yerr (m)';
            app.YerrmEditField = uieditfield(app.Panel2, 'numeric');
            app.YerrmEditField.Editable = 'off';
            app.ZerrmEditFieldLabel = uilabel(app.Panel2);
            app.ZerrmEditFieldLabel.Text = 'Zerr (m)';
            app.ZerrmEditField = uieditfield(app.Panel2, 'numeric');
            app.ZerrmEditField.Editable = 'off';
            app.ErrDistancemEditFieldLabel = uilabel(app.Panel2);
            app.ErrDistancemEditFieldLabel.Text = 'Err Distance (m)';
            app.ErrDistancemEditField = uieditfield(app.Panel2, 'numeric');
            app.ErrDistancemEditField.Editable = 'off';
            app.XmEditFieldLabel = uilabel(app.Panel2);
            app.XmEditFieldLabel.Text = 'X(m)';
            app.XmCalcEditField = uieditfield(app.Panel2, 'numeric');
            app.XmCalcEditField.Editable = 'off';
            app.YmEditFieldLabel = uilabel(app.Panel2);
            app.YmEditFieldLabel.Text = 'Y(m)';
            app.YmCalcEditField = uieditfield(app.Panel2, 'numeric');
            app.YmCalcEditField.Editable = 'off';
            app.ZmEditFieldLabel = uilabel(app.Panel2);
            app.ZmEditFieldLabel.Text = 'Z(m)';
            app.ZmCalcEditField = uieditfield(app.Panel2, 'numeric');
            app.ZmCalcEditField.Editable = 'off';
            app.CameraSettingsPanel = uipanel(app.UIFigure);
            app.CameraSettingsPanel.Title = 'Panel3';
            app.X = uilabel(app.CameraSettingsPanel);
            app.X.Text = 'X';
            app.focalLength_Slider = uislider(app.CameraSettingsPanel);
            app.focalLength_Slider.Limits = [0 200];
            app.focalLength_Slider.ValueChangedFcn = createCallbackFcn(app, @focalLength_SliderValueChanged, true);
            app.xmLabel_11 = uilabel(app.CameraSettingsPanel);
            app.xmLabel_11.Text = 'Focal Length (mm)';
            app.focalLength_EF = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.focalLength_EF.Limits = [0 200];
            app.focalLength_EF.ValueChangedFcn = createCallbackFcn(app, @focalLength_EFValueChanged, true);
            app.pixelSize_Slider = uislider(app.CameraSettingsPanel);
            app.pixelSize_Slider.Limits = [0 10];
            app.pixelSize_Slider.ValueChangedFcn = createCallbackFcn(app, @pixelSize_SliderValueChanged, true);
            app.xmLabel_12 = uilabel(app.CameraSettingsPanel);
            app.xmLabel_12.Text = 'Pixel Size (um)';
            app.pixelSize_EF = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.pixelSize_EF.Limits = [0 10];
            app.pixelSize_EF.ValueChangedFcn = createCallbackFcn(app, @pixelSize_EFValueChanged, true);
            app.baseline_Slider = uislider(app.CameraSettingsPanel);
            app.baseline_Slider.ValueChangedFcn = createCallbackFcn(app, @baseline_SliderValueChanged, true);
            app.xmLabel_13 = uilabel(app.CameraSettingsPanel);
            app.xmLabel_13.Text = 'Baseline (mm)';
            app.baseline_EF = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.baseline_EF.Limits = [0 100];
            app.baseline_EF.ValueChangedFcn = createCallbackFcn(app, @baseline_EFValueChanged, true);
            app.CamCol_Slider = uislider(app.CameraSettingsPanel);
            app.CamCol_Slider.Limits = [0 3840];
            app.CamCol_Slider.MajorTicks = [];
            app.CamCol_Slider.ValueChangedFcn = createCallbackFcn(app, @CamCol_SliderValueChanged, true);
            app.xmLabel_14 = uilabel(app.CameraSettingsPanel);
            app.xmLabel_14.Text = 'Rows x Columns';
            app.CamCol_EF = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.CamCol_EF.Limits = [0 3840];
            app.CamCol_EF.ValueChangedFcn = createCallbackFcn(app, @CamCol_EFValueChanged, true);
            app.CamRows_Slider = uislider(app.CameraSettingsPanel);
            app.CamRows_Slider.Limits = [0 2160];
            app.CamRows_Slider.ValueChangedFcn = createCallbackFcn(app, @CamRows_SliderValueChanged, true);
            app.CamRows_EF = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.CamRows_EF.Limits = [0 2160];
            app.CamRows_EF.ValueChangedFcn = createCallbackFcn(app, @CamRows_EFValueChanged, true);
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Panel4';
            app.Left = uiaxes(app.GraphPanel);
            title(app.Left, 'Left')
            xlabel(app.Left, 'X')
            ylabel(app.Left, 'Y')
            zlabel(app.Left, 'Z')
            app.Right = uiaxes(app.GraphPanel);
            title(app.Right, 'Right')
            xlabel(app.Right, 'Z')
            ylabel(app.Right, 'Y')
            zlabel(app.Right, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end