classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageOutputsPanel         matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        DSpectrogramOutputPanel   matlab.ui.container.Panel
        ActualBallPositionLabel   matlab.ui.control.Label
        RightCameraOriginBallPositionLabel  matlab.ui.control.Label
        LeftCameraOriginBallPositionLabel  matlab.ui.control.Label
        YCordEditField_3          matlab.ui.control.NumericEditField
        YCordEditField_3Label     matlab.ui.control.Label
        XCordEditField_3          matlab.ui.control.EditField
        XCordEditField_3Label     matlab.ui.control.Label
        YCordEditField_2          matlab.ui.control.NumericEditField
        YCordEditField_2Label     matlab.ui.control.Label
        XCordEditField_2          matlab.ui.control.EditField
        XCordEditField_2Label     matlab.ui.control.Label
        ZCordEditField            matlab.ui.control.NumericEditField
        ZCordEditFieldLabel       matlab.ui.control.Label
        YCordEditField            matlab.ui.control.NumericEditField
        YCordEditFieldLabel       matlab.ui.control.Label
        XCordEditField            matlab.ui.control.EditField
        XCordEditFieldLabel       matlab.ui.control.Label
        MethodPanel               matlab.ui.container.Panel
        ColorMethodDropDown       matlab.ui.control.DropDown
        ColorMethodDropDownLabel  matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ImageSelectionPanel       matlab.ui.container.Panel
        ImageSetDropDown          matlab.ui.control.DropDown
        ImageSetDropDownLabel     matlab.ui.control.Label
        CameraPanel               matlab.ui.container.Panel
        CamRows_EF                matlab.ui.control.NumericEditField
        CamRows_Slider            matlab.ui.control.Slider
        CamCol_EF                 matlab.ui.control.NumericEditField
        xmLabel_4                 matlab.ui.control.Label
        CamCol_Slider             matlab.ui.control.Slider
        baseline_EF               matlab.ui.control.NumericEditField
        xmLabel_3                 matlab.ui.control.Label
        baseline_Slider           matlab.ui.control.Slider
        pixelSize_EF              matlab.ui.control.NumericEditField
        xmLabel_2                 matlab.ui.control.Label
        pixelSize_Slider          matlab.ui.control.Slider
        focalLength_EF            matlab.ui.control.NumericEditField
        xmLabel                   matlab.ui.control.Label
        focalLength_Slider        matlab.ui.control.Slider
        X                         matlab.ui.control.Label
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ImageSets = {
                {"images/left5.jpg", "images/right5.jpg"}
                {"images/left6.jpg", "images/right6.jpg"}
                {"images/left7.jpg", "images/right7.jpg"}
                {"images/left8.jpg", "images/right8.jpg"}
                {"images/left9.jpg", "images/right9.jpg"}    
            };

            setappdata(app.UIFigure, "ImageSets", ImageSets);

            app.ImageSetDropDown.Items = {
                'Set 1', 'Set 2', 'Set 3', 'Set 4', 'Set 5'
            };

            app.ImageSetDropDown.Value = 'Set 1';
            app.ImageSetDropDown.ItemsData = 1:5;

            ax1 = app.UIAxes;   % repeat for RightAxes / MethodAxes
            ax1.Toolbar.Visible = 'off';
            ax1.XTick = [];
            ax1.YTick = [];
            ax1.Box = 'off';
            ax1.XColor = 'none';
            ax1.YColor = 'none';
            ax1.LooseInset = [0 0 0 0];

            ax2 = app.UIAxes2;   % repeat for RightAxes / MethodAxes
            ax2.Toolbar.Visible = 'off';
            ax2.XTick = [];
            ax2.YTick = [];
            ax2.Box = 'off';
            ax2.XColor = 'none';
            ax2.YColor = 'none';
            ax2.LooseInset = [0 0 0 0];

            ax3 = app.UIAxes3;   % repeat for RightAxes / MethodAxes
            ax3.Toolbar.Visible = 'off';
            ax3.XTick = [];
            ax3.YTick = [];
            ax3.Box = 'off';
            ax3.XColor = 'none';
            ax3.YColor = 'none';
            ax3.LooseInset = [0 0 0 0];

            ax4 = app.UIAxes4;   % repeat for RightAxes / MethodAxes
            ax4.Toolbar.Visible = 'off';
            ax4.XTick = [];
            ax4.YTick = [];
            ax4.Box = 'off';
            ax4.XColor = 'none';
            ax4.YColor = 'none';
            ax4.LooseInset = [0 0 0 0];
        end

        % Value changed function: focalLength_EF
        function focalLength_EFValueChanged(app, event)
            app.focalLength_Slider.Value = app.focalLength_EF.Value;
            
        end

        % Value changed function: focalLength_Slider
        function focalLength_SliderValueChanged(app, event)
            app.focalLength_EF.Value = app.focalLength_Slider.Value;
            
        end

        % Value changed function: pixelSize_EF
        function pixelSize_EFValueChanged(app, event)
            app.pixelSize_Slider.Value = app.pixelSize_EF.Value;
            
        end

        % Value changed function: pixelSize_Slider
        function pixelSize_SliderValueChanged(app, event)
            app.pixelSize_EF.Value = app.pixelSize_Slider.Value;
            
        end

        % Value changed function: baseline_Slider
        function baseline_SliderValueChanged(app, event)
            app.baseline_EF.Value = app.baseline_Slider.Value;
            
        end

        % Value changed function: baseline_EF
        function baseline_EFValueChanged(app, event)
            app.baseline_Slider.Value = app.baseline_EF.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % get image sets
            imageSets = getappdata(app.UIFigure, "ImageSets");

            % which set
            setID = app.ImageSetDropDown.Value;

            % load images depending on SetID
            leftPath = imageSets{setID}{1};
            rightPath = imageSets{setID}{2};

            leftIm = imread(leftPath);
            rightIm = imread(rightPath);

            ax1 = app.UIAxes;
            ax2 = app.UIAxes2
            
            imshow(leftIm, 'Parent', ax1);
            imshow(rightIm, 'Parent', ax2);

            app.CamRows_EF.Value = size(leftIm, 2);
            app.CamCol_EF.Value = size(leftIm, 1);
            
        end

        % Value changed function: CamCol_Slider
        function CamCol_SliderValueChanged(app, event)
            app.CamCol_EF.Value = app.CamCol_Slider.Value;
            
        end

        % Value changed function: CamCol_EF
        function CamCol_EFValueChanged(app, event)
            app.CamCol_Slider.Value = app.CamCol_EF.Value;
            
        end

        % Value changed function: CamRows_EF
        function CamRows_EFValueChanged(app, event)
            app.CamRows_Slider.Value = app.CamRows_EF.Value;
            
        end

        % Value changed function: CamRows_Slider
        function CamRows_SliderValueChanged(app, event)
            app.CamRows_EF.Value = app.CamRows_Slider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.X = uilabel(app.CameraPanel);
            app.X.Text = 'X';
            app.focalLength_Slider = uislider(app.CameraPanel);
            app.focalLength_Slider.Limits = [0 200];
            app.focalLength_Slider.ValueChangedFcn = createCallbackFcn(app, @focalLength_SliderValueChanged, true);
            app.xmLabel = uilabel(app.CameraPanel);
            app.xmLabel.Text = 'Focal Length (mm)';
            app.focalLength_EF = uieditfield(app.CameraPanel, 'numeric');
            app.focalLength_EF.Limits = [0 200];
            app.focalLength_EF.ValueChangedFcn = createCallbackFcn(app, @focalLength_EFValueChanged, true);
            app.pixelSize_Slider = uislider(app.CameraPanel);
            app.pixelSize_Slider.Limits = [0 10];
            app.pixelSize_Slider.ValueChangedFcn = createCallbackFcn(app, @pixelSize_SliderValueChanged, true);
            app.xmLabel_2 = uilabel(app.CameraPanel);
            app.xmLabel_2.Text = 'Pixel Size (um)';
            app.pixelSize_EF = uieditfield(app.CameraPanel, 'numeric');
            app.pixelSize_EF.Limits = [0 10];
            app.pixelSize_EF.ValueChangedFcn = createCallbackFcn(app, @pixelSize_EFValueChanged, true);
            app.baseline_Slider = uislider(app.CameraPanel);
            app.baseline_Slider.ValueChangedFcn = createCallbackFcn(app, @baseline_SliderValueChanged, true);
            app.xmLabel_3 = uilabel(app.CameraPanel);
            app.xmLabel_3.Text = 'Baseline (mm)';
            app.baseline_EF = uieditfield(app.CameraPanel, 'numeric');
            app.baseline_EF.Limits = [0 100];
            app.baseline_EF.ValueChangedFcn = createCallbackFcn(app, @baseline_EFValueChanged, true);
            app.CamCol_Slider = uislider(app.CameraPanel);
            app.CamCol_Slider.Limits = [0 3840];
            app.CamCol_Slider.MajorTicks = [];
            app.CamCol_Slider.ValueChangedFcn = createCallbackFcn(app, @CamCol_SliderValueChanged, true);
            app.xmLabel_4 = uilabel(app.CameraPanel);
            app.xmLabel_4.Text = 'Rows x Columns';
            app.CamCol_EF = uieditfield(app.CameraPanel, 'numeric');
            app.CamCol_EF.Limits = [0 3840];
            app.CamCol_EF.ValueChangedFcn = createCallbackFcn(app, @CamCol_EFValueChanged, true);
            app.CamRows_Slider = uislider(app.CameraPanel);
            app.CamRows_Slider.Limits = [0 2160];
            app.CamRows_Slider.ValueChangedFcn = createCallbackFcn(app, @CamRows_SliderValueChanged, true);
            app.CamRows_EF = uieditfield(app.CameraPanel, 'numeric');
            app.CamRows_EF.Limits = [0 2160];
            app.CamRows_EF.ValueChangedFcn = createCallbackFcn(app, @CamRows_EFValueChanged, true);
            app.ImageSelectionPanel = uipanel(app.UIFigure);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.ImageSetDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.ImageSetDropDownLabel.Text = 'Image Set';
            app.ImageSetDropDown = uidropdown(app.ImageSelectionPanel);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MethodPanel = uipanel(app.UIFigure);
            app.MethodPanel.Title = 'Method';
            app.ColorMethodDropDownLabel = uilabel(app.MethodPanel);
            app.ColorMethodDropDownLabel.Text = 'Color Method';
            app.ColorMethodDropDown = uidropdown(app.MethodPanel);
            app.DSpectrogramOutputPanel = uipanel(app.UIFigure);
            app.DSpectrogramOutputPanel.Title = '3D Spectrogram Output';
            app.XCordEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.XCordEditFieldLabel.Text = 'X Cord';
            app.XCordEditField = uieditfield(app.DSpectrogramOutputPanel, 'text');
            app.YCordEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditFieldLabel.Text = 'Y Cord';
            app.YCordEditField = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.ZCordEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.ZCordEditFieldLabel.Text = 'Z Cord';
            app.ZCordEditField = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.XCordEditField_2Label = uilabel(app.DSpectrogramOutputPanel);
            app.XCordEditField_2Label.Text = 'X Cord';
            app.XCordEditField_2 = uieditfield(app.DSpectrogramOutputPanel, 'text');
            app.YCordEditField_2Label = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditField_2Label.Text = 'Y Cord';
            app.YCordEditField_2 = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.XCordEditField_3Label = uilabel(app.DSpectrogramOutputPanel);
            app.XCordEditField_3Label.Text = 'X Cord';
            app.XCordEditField_3 = uieditfield(app.DSpectrogramOutputPanel, 'text');
            app.YCordEditField_3Label = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditField_3Label.Text = 'Y Cord';
            app.YCordEditField_3 = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.LeftCameraOriginBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.LeftCameraOriginBallPositionLabel.Text = {'Left Camera (Origin)'; 'Ball Position'};
            app.RightCameraOriginBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.RightCameraOriginBallPositionLabel.Text = {'Right Camera (Origin)'; 'Ball Position'};
            app.ActualBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.ActualBallPositionLabel.Text = 'Actual Ball Position';
            app.ImageOutputsPanel = uipanel(app.UIFigure);
            app.ImageOutputsPanel.Title = 'Image Outputs';
            app.GridLayout = uigridlayout(app.ImageOutputsPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw Left Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Raw Right Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end