classdef Copy_of_lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RunBlenderButton          matlab.ui.control.Button
        CameraPanel               matlab.ui.container.Panel
        CamRows_EF                matlab.ui.control.NumericEditField
        CamRows_Slider            matlab.ui.control.Slider
        CamCol_EF                 matlab.ui.control.NumericEditField
        xmLabel_4                 matlab.ui.control.Label
        CamCol_Slider             matlab.ui.control.Slider
        baseline_EF               matlab.ui.control.NumericEditField
        xmLabel_3                 matlab.ui.control.Label
        baseline_Slider           matlab.ui.control.Slider
        pixelSize_EF              matlab.ui.control.NumericEditField
        xmLabel_2                 matlab.ui.control.Label
        pixelSize_Slider          matlab.ui.control.Slider
        focalLength_EF            matlab.ui.control.NumericEditField
        xmLabel                   matlab.ui.control.Label
        focalLength_Slider        matlab.ui.control.Slider
        X                         matlab.ui.control.Label
        ImageOutputsPanel         matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        DSpectrogramOutputPanel   matlab.ui.container.Panel
        XCord_LC_EF               matlab.ui.control.NumericEditField
        XCordLabel_3              matlab.ui.control.Label
        XCord_RC_EF               matlab.ui.control.NumericEditField
        XCordLabel_2              matlab.ui.control.Label
        XCord_3D_EF               matlab.ui.control.NumericEditField
        XCordLabel                matlab.ui.control.Label
        RuntimemsEditField        matlab.ui.control.NumericEditField
        RuntimemsEditFieldLabel   matlab.ui.control.Label
        ActualBallPositionLabel   matlab.ui.control.Label
        RightCameraOriginBallPositionLabel  matlab.ui.control.Label
        LeftCameraOriginBallPositionLabel  matlab.ui.control.Label
        YCord_LC_EF               matlab.ui.control.NumericEditField
        YCordEditField_3Label     matlab.ui.control.Label
        YCord_RC_EF               matlab.ui.control.NumericEditField
        YCordEditField_2Label     matlab.ui.control.Label
        ZCord_3D_EF               matlab.ui.control.NumericEditField
        ZCordEditFieldLabel       matlab.ui.control.Label
        YCord_3D_EF               matlab.ui.control.NumericEditField
        YCordEditFieldLabel       matlab.ui.control.Label
        MethodPanel               matlab.ui.container.Panel
        ColCutEditField           matlab.ui.control.NumericEditField
        ColCutEditFieldLabel      matlab.ui.control.Label
        RowCutEditField           matlab.ui.control.NumericEditField
        RowCutEditFieldLabel      matlab.ui.control.Label
        ColorMethodDropDown       matlab.ui.control.DropDown
        ColorMethodDropDownLabel  matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ImageSelectionPanel       matlab.ui.container.Panel
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImageSets % Array for the images pulled from blender
        SetID % Description

        % Server INIT parameters
        server_ip   = '127.0.0.1';     %IP address of the Unity Server
        server_port = 55001;           %Server Port of the Unity Sever
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defaultBaseline_mm   = 50;     % mm
            defaultFocal_mm      = 6;      % mm
            defaultPixel_um      = 6;      % um (0.006 mm)

            app.baseline_EF.Value     = defaultBaseline_mm;
            app.baseline_Slider.Value = defaultBaseline_mm;

            app.focalLength_EF.Value     = defaultFocal_mm;
            app.focalLength_Slider.Value = defaultFocal_mm;

            app.pixelSize_EF.Value     = defaultPixel_um;
            app.pixelSize_Slider.Value = defaultPixel_um;
            
            leftPath  = imageSets{setID}{1};
            rightPath = imageSets{setID}{2};
            
            leftIm  = imread(leftPath);
            rightIm = imread(rightPath);
            
            % Show raw images immediately
            imshow(leftIm,  'Parent', app.UIAxes);
            imshow(rightIm, 'Parent', app.UIAxes2);
            
            % Cache for Calculate
            setappdata(app.UIFigure, "LeftIm",  leftIm);
            setappdata(app.UIFigure, "RightIm", rightIm);

            ax1 = app.UIAxes;   % repeat for RightAxes / MethodAxes
            ax1.Toolbar.Visible = 'off';
            ax1.XTick = [];
            ax1.YTick = [];
            ax1.Box = 'off';
            ax1.XColor = 'none';
            ax1.YColor = 'none';
            ax1.LooseInset = [0 0 0 0];

            ax2 = app.UIAxes2;   % repeat for RightAxes / MethodAxes
            ax2.Toolbar.Visible = 'off';
            ax2.XTick = [];
            ax2.YTick = [];
            ax2.Box = 'off';
            ax2.XColor = 'none';
            ax2.YColor = 'none';
            ax2.LooseInset = [0 0 0 0];

            ax3 = app.UIAxes3;   % repeat for RightAxes / MethodAxes
            ax3.Toolbar.Visible = 'off';
            ax3.XTick = [];
            ax3.YTick = [];
            ax3.Box = 'off';
            ax3.XColor = 'none';
            ax3.YColor = 'none';
            ax3.LooseInset = [0 0 0 0];

            ax4 = app.UIAxes4;   % repeat for RightAxes / MethodAxes
            ax4.Toolbar.Visible = 'off';
            ax4.XTick = [];
            ax4.YTick = [];
            ax4.Box = 'off';
            ax4.XColor = 'none';
            ax4.YColor = 'none';
            ax4.LooseInset = [0 0 0 0];
        end

        % Value changed function: focalLength_EF
        function focalLength_EFValueChanged(app, event)
            app.focalLength_Slider.Value = app.focalLength_EF.Value;
            
        end

        % Value changed function: focalLength_Slider
        function focalLength_SliderValueChanged(app, event)
            app.focalLength_EF.Value = app.focalLength_Slider.Value;
            
        end

        % Value changed function: pixelSize_EF
        function pixelSize_EFValueChanged(app, event)
            app.pixelSize_Slider.Value = app.pixelSize_EF.Value;
            
        end

        % Value changed function: pixelSize_Slider
        function pixelSize_SliderValueChanged(app, event)
            app.pixelSize_EF.Value = app.pixelSize_Slider.Value;
            
        end

        % Value changed function: baseline_Slider
        function baseline_SliderValueChanged(app, event)
            app.baseline_EF.Value = app.baseline_Slider.Value;
            
        end

        % Value changed function: baseline_EF
        function baseline_EFValueChanged(app, event)
            app.baseline_Slider.Value = app.baseline_EF.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            profile on;
            function [u, v, dbg, center, radius] = grayscaleFindCentroid(img)
                % convert image to grayscale (replace which math earlier,
                % will be faster
                grayImg = rgb2gray(img);
                
                % find circles
                [centers, radii, metric] = imfindcircles(grayImg, [15 200], ...
                    "ObjectPolarity",'bright', ...
                    'Sensitivity',0.90 ...
                    );

                if isempty(centers)
                    error('No circles detected');
                end

                % best circle
                [~, i] = max(metric);

                % centroid
                u = centers(i, 1);
                v = centers(i, 2);

                % extra outputs
                center = centers(i, :);
                radius = radii(i);

                % dbg image
                dbg = grayImg;

            end

            function [u, v, dbg, center, radius] = bwFindCentroid(img)
                % convert to grayscale
                grayImg = rgb2gray(img);

                % binarize it to B&W
                binarizedImg = imbinarize(grayImg);

                % region mask
                [H, W] = size(binarizedImg);

                rowCut = round(app.RowCutEditField.Value/100 * H);
                colCut = round(app.ColCutEditField.Value/100 * W);

                mask = false(H, W);
                mask(1:rowCut, colCut:W) = true;

                % mask for region of interest
                binarizedROI = binarizedImg;
                binarizedROI(~mask) = 0;

                % blobs
                cc = bwconncomp(binarizedROI);
                if cc.NumObjects == 0
                    error('No blobs detected');
                end

                numPixels = cellfun(@numel, cc.PixelIdxList);
                [~, idx] = max(numPixels);

                ballMask = false(size(binarizedROI));
                ballMask(cc.PixelIdxList{idx}) = true;

                % get stats
                stats = regionprops(ballMask, 'Centroid', 'Area');
                if isempty(stats)
                    error('No centroid found');
                end

                % values
                u = stats.Centroid(1);
                v = stats.Centroid(2);
                center = [u, v];
                A = stats.Area;
                radius = sqrt(A/pi);

                dbg = ballMask;
            end

            function [u, v, dbg, center, radius] = yCbCrFindCentroid(img)            
                % convert to ycbcr
                ycc = rgb2ycbcr(img);
                Y  = ycc(:,:,1);
                Cb = ycc(:,:,2);
                Cr = ycc(:,:,3);
            
                % color mask
                colorMask = (Cr > 120) & (Cb < 120);
                
                % spatial mask
                [H, W] = size(colorMask);
                rowCut = round(app.RowCutEditField.Value/100 * H);
                colCut = round(app.ColCutEditField.Value/100 * W);
            
                % clamp to valid bounds
                rowCut = max(1, min(rowCut, H));
                colCut = max(1, min(colCut, W));
            
                spatialMask = false(H, W);
                spatialMask(1:rowCut, colCut:W) = true;
            
                % combine mask
                mask = colorMask & spatialMask;
                mask = bwareaopen(mask, 200);
                mask = imfill(mask, 'holes');
            
                % Use Y channel for imfindcircles, zero out background.
                dbg = Y;
                dbg(~mask) = 0;
            
                % circle detection
                [centers, radii, metric] = imfindcircles(dbg, [15 200], ...
                    'ObjectPolarity', 'bright', ...
                    'Sensitivity', 0.95);
            
                if isempty(centers)
                    error('No circles detected (YCbCr method).');
                end
            
                % Best circle only
                [~, i] = max(metric);
                center = centers(i, :);
                radius = radii(i);
            
                u = center(1);
                v = center(2);

            end

            function [z, x, y, d] = find3DCentroid(fpx, b, uL, vL, uR, cX, cY)
                d = uL - uR; % disparity 

                z = (fpx * b) / d; % depth [mm]
                x = (uL - cX) * z / fpx; % x cord [mm]
                y = -(vL - cY) * z / fpx; % y cord [mm]
            end
            
            leftIm  = getappdata(app.UIFigure, "LeftIm");
            rightIm = getappdata(app.UIFigure, "RightIm");
            
            if isempty(leftIm) || isempty(rightIm)
                uialert(app.UIFigure, 'Please select an image set first.', 'No images loaded');
                return;
            end

            ax3 = app.UIAxes3;
            ax4 = app.UIAxes4;

            method = app.ColorMethodDropDown.Value;
            switch method 
                case 'Grayscale'
                    try
                        [uL,vL,dbgL, centerL, radiusL] = grayscaleFindCentroid(leftIm);
                        [uR,vR,dbgR, centerR, radiusR] = grayscaleFindCentroid(rightIm);
                    catch ME
                        uialert(app.UIFigure, ...
                            'No circles were detected in one or both images.', ...
                            'Detection Failed');
                        return;   % STOP the rest of Calculate
                    end
                    % Draw the left image
                    imshow(dbgL, 'Parent', ax3); hold(app.UIAxes3, 'on');
                    viscircles(app.UIAxes3, centerL, radiusL, 'EdgeColor', 'r');
                    plot(app.UIAxes3, uL, vL, 'LineWidth', 2);
                    hold(app.UIAxes3,'off');

                    % draw the right image
                    imshow(dbgR, 'Parent', ax4); hold(app.UIAxes4, 'on');
                    viscircles(app.UIAxes4, centerR, radiusR, 'EdgeColor', 'b');
                    plot(app.UIAxes4, uR, vR, 'LineWidth', 2);
                    hold(app.UIAxes4,'off');  

                case 'Binarize (B&W)'
                    try
                        [uL,vL,dbgL, centerL, radiusL] = bwFindCentroid(leftIm);
                        [uR,vR,dbgR, centerR, radiusR] = bwFindCentroid(rightIm);
                    catch ME
                        uialert(app.UIFigure, ...
                            'No circles were detected in one or both images.', ...
                            'Detection failed.' ...
                            );
                        return;
                    end
                    % draw the left image
                    imshow(dbgL, 'Parent', ax3); hold(app.UIAxes3, 'on');
                    viscircles(app.UIAxes3, centerL, radiusL, 'EdgeColor', 'r');
                    plot(app.UIAxes3, uL, vL, 'LineWidth', 2);
                    hold(app.UIAxes3,'off');

                    % draw the right image
                    imshow(dbgR, 'Parent', ax4); hold(app.UIAxes4, 'on');
                    viscircles(app.UIAxes4, centerR, radiusR, 'EdgeColor', 'b');
                    plot(app.UIAxes4, uR, vR, 'LineWidth', 2);
                    hold(app.UIAxes4,'off');  
                    
                case 'YCbCr'
                    try
                        [uL,vL,dbgL, centerL, radiusL] = yCbCrFindCentroid(leftIm);
                        [uR,vR,dbgR, centerR, radiusR] = yCbCrFindCentroid(rightIm);
                    catch
                        uialert(app.UIFigure, ...
                            'No circles were detected in one or both images.', ...
                            'Detection failed.' ...
                        );
                        return;
                    end

                    % draw the left image
                    imshow(dbgL, 'Parent', ax3); hold(app.UIAxes3, 'on');
                    viscircles(app.UIAxes3, centerL, radiusL, 'EdgeColor', 'r');
                    plot(app.UIAxes3, uL, vL, 'LineWidth', 2);
                    hold(app.UIAxes3,'off');

                    % draw the right image
                    imshow(dbgR, 'Parent', ax4); hold(app.UIAxes4, 'on');
                    viscircles(app.UIAxes4, centerR, radiusR, 'EdgeColor', 'b');
                    plot(app.UIAxes4, uR, vR, 'LineWidth', 2);
                    hold(app.UIAxes4,'off');  
                otherwise
                    error('Unkown method selected');
            end

            % output camera locations to gui
            app.XCord_LC_EF.Value = uL;
            app.YCord_LC_EF.Value = vL;

            app.XCord_RC_EF.Value = uR;
            app.YCord_RC_EF.Value = vR;

            % Update resolution fields
            H = size(leftIm,1);
            W = size(leftIm,2);
            app.CamRows_EF.Value = H;
            app.CamCol_EF.Value  = W;

            % Get camera properties
            b = app.baseline_EF.Value;
            f_mm = app.focalLength_EF.Value;
            px_mm = app.pixelSize_EF.Value / 1000;
            fpx = f_mm / px_mm;

            cX = W/2;
            cY = H/2;

            % triangulate
            [z, x, y, d] = find3DCentroid(fpx, b, uL, vL, uR, cX, cY);

            app.ZCord_3D_EF.Value = z
            app.XCord_3D_EF.Value = x;
            app.YCord_3D_EF.Value = y;
        
            profile off;
            p = profile('info');
            totalTime = p.FunctionTable(1).TotalTime * 1000; % Total time of the top-level function
            
            app.RuntimemsEditField.Value = totalTime; % display runtime (ms)
        end

        % Value changed function: CamCol_Slider
        function CamCol_SliderValueChanged(app, event)
            app.CamCol_EF.Value = app.CamCol_Slider.Value;
            
        end

        % Value changed function: CamCol_EF
        function CamCol_EFValueChanged(app, event)
            app.CamCol_Slider.Value = app.CamCol_EF.Value;
            
        end

        % Value changed function: CamRows_EF
        function CamRows_EFValueChanged(app, event)
            app.CamRows_Slider.Value = app.CamRows_EF.Value;
            
        end

        % Value changed function: CamRows_Slider
        function CamRows_SliderValueChanged(app, event)
            app.CamRows_EF.Value = app.CamRows_Slider.Value;
            
        end

        % Callback function: not associated with a component
        function ImageSetDropDownValueChanged(app, event)

            imageSets = getappdata(app.UIFigure, "ImageSets");
            setID = app.ImageSetDropDown.Value;
    
            leftPath  = imageSets{setID}{1};
            rightPath = imageSets{setID}{2};
    
            leftIm  = imread(leftPath);
            rightIm = imread(rightPath);
    
            % Display raw images
            imshow(leftIm,  'Parent', app.UIAxes);
            imshow(rightIm, 'Parent', app.UIAxes2);
    
            % Save images for Calculate to use later
            setappdata(app.UIFigure, "LeftIm",  leftIm);
            setappdata(app.UIFigure, "RightIm", rightIm);
            
        end

        % Callback function
        function TESTCHECKCheckBoxValueChanged(app, event)
            value = app.TESTCHECKCheckBox.Value;
            if value
                app.TEST_SLIDERSlider.Enable = 'on'
            else
                app.TEST_SLIDERSlider.Enable = 'off'
            end
        end

        % Button pushed function: RunBlenderButton
        function RunBlenderButtonPushed(app, event)
                        
            
            client = tcpclient(app.server_ip,app.server_port,"Timeout",20);
            width = 1920;
            height = 1080;

            for counter = 1:10
                try
                    image = blenderLink(client,width,height,1,counter,counter,0,0,0,"tennisBall");
                catch 
                    uialert(app.UIFigure, ...
                        'Ensure Blender server is running', ...
                        'Could not connect to server...' ...
                    );
                    return
                end
                app.ImageSet{counter} = image;
                imagesc(image)
                set(gcf, 'Position', get(0, 'Screensize'));
                axis off
            end

            % CHANGE THIS
            setappdata(app.UIFigure, "ImageSets", app.ImageSets);

            app.ImageSetDropDown.Items = app.ImageSet
            
            app.ImageSetDropDown.Value = 'Set 1';
            app.ImageSetDropDown.ItemsData = app.ImageSet{1:5};

            % Use ItemsData => Value must be numeric
            app.ImageSetDropDown.Value = 1;   % NOT 'Set 1'
            
            % Manually load the default set once (because ValueChanged won't fire at startup)
            app.ImageSets = getappdata(app.UIFigure, "ImageSets");
            app.setID = app.ImageSetDropDown.Value;
            % TO THIS
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageSelectionPanel = uipanel(app.UIFigure);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.Button = uibutton(app.ImageSelectionPanel, 'push');
            app.Button.Text = '🢐';
            app.Button_2 = uibutton(app.ImageSelectionPanel, 'push');
            app.Button_3 = uibutton(app.ImageSelectionPanel, 'push');
            app.Button_3.Text = '🢒';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MethodPanel = uipanel(app.UIFigure);
            app.MethodPanel.Title = 'Method';
            app.ColorMethodDropDownLabel = uilabel(app.MethodPanel);
            app.ColorMethodDropDownLabel.Text = 'Color Method';
            app.ColorMethodDropDown = uidropdown(app.MethodPanel);
            app.ColorMethodDropDown.Items = {'Grayscale', 'Binarize (B&W)', 'YCbCr'};
            app.ColorMethodDropDown.Value = 'Grayscale';
            app.RowCutEditFieldLabel = uilabel(app.MethodPanel);
            app.RowCutEditFieldLabel.Text = {'Row'; 'Cut %'};
            app.RowCutEditField = uieditfield(app.MethodPanel, 'numeric');
            app.RowCutEditField.Value = 70;
            app.ColCutEditFieldLabel = uilabel(app.MethodPanel);
            app.ColCutEditFieldLabel.Text = {'Col'; 'Cut %'};
            app.ColCutEditField = uieditfield(app.MethodPanel, 'numeric');
            app.ColCutEditField.Value = 1;
            app.DSpectrogramOutputPanel = uipanel(app.UIFigure);
            app.DSpectrogramOutputPanel.Title = '3D Spectrogram Output';
            app.YCordEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditFieldLabel.Text = 'Y Cord';
            app.YCord_3D_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.YCord_3D_EF.Editable = 'off';
            app.ZCordEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.ZCordEditFieldLabel.Text = 'Z Cord';
            app.ZCord_3D_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.ZCord_3D_EF.Editable = 'off';
            app.YCordEditField_2Label = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditField_2Label.Text = 'Y Cord';
            app.YCord_RC_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.YCord_RC_EF.Editable = 'off';
            app.YCordEditField_3Label = uilabel(app.DSpectrogramOutputPanel);
            app.YCordEditField_3Label.Text = 'Y Cord';
            app.YCord_LC_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.YCord_LC_EF.Editable = 'off';
            app.LeftCameraOriginBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.LeftCameraOriginBallPositionLabel.Text = {'Left Camera (Origin)'; 'Ball Position'};
            app.RightCameraOriginBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.RightCameraOriginBallPositionLabel.Text = {'Right Camera (Origin)'; 'Ball Position'};
            app.ActualBallPositionLabel = uilabel(app.DSpectrogramOutputPanel);
            app.ActualBallPositionLabel.Text = 'Actual Ball Position';
            app.RuntimemsEditFieldLabel = uilabel(app.DSpectrogramOutputPanel);
            app.RuntimemsEditFieldLabel.Text = 'Runtime (ms)';
            app.RuntimemsEditField = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.RuntimemsEditField.Editable = 'off';
            app.XCordLabel = uilabel(app.DSpectrogramOutputPanel);
            app.XCordLabel.Text = 'X Cord';
            app.XCord_3D_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.XCord_3D_EF.Editable = 'off';
            app.XCordLabel_2 = uilabel(app.DSpectrogramOutputPanel);
            app.XCordLabel_2.Text = 'X Cord';
            app.XCord_RC_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.XCord_RC_EF.Editable = 'off';
            app.XCordLabel_3 = uilabel(app.DSpectrogramOutputPanel);
            app.XCordLabel_3.Text = 'X Cord';
            app.XCord_LC_EF = uieditfield(app.DSpectrogramOutputPanel, 'numeric');
            app.XCord_LC_EF.Editable = 'off';
            app.ImageOutputsPanel = uipanel(app.UIFigure);
            app.ImageOutputsPanel.Title = 'Image Outputs';
            app.GridLayout = uigridlayout(app.ImageOutputsPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Raw Left Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Raw Right Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Circle Detected Left Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Circle Detected Right Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.X = uilabel(app.CameraPanel);
            app.X.Text = 'X';
            app.focalLength_Slider = uislider(app.CameraPanel);
            app.focalLength_Slider.Limits = [0 200];
            app.focalLength_Slider.ValueChangedFcn = createCallbackFcn(app, @focalLength_SliderValueChanged, true);
            app.xmLabel = uilabel(app.CameraPanel);
            app.xmLabel.Text = 'Focal Length (mm)';
            app.focalLength_EF = uieditfield(app.CameraPanel, 'numeric');
            app.focalLength_EF.Limits = [0 200];
            app.focalLength_EF.ValueChangedFcn = createCallbackFcn(app, @focalLength_EFValueChanged, true);
            app.pixelSize_Slider = uislider(app.CameraPanel);
            app.pixelSize_Slider.Limits = [0 10];
            app.pixelSize_Slider.ValueChangedFcn = createCallbackFcn(app, @pixelSize_SliderValueChanged, true);
            app.xmLabel_2 = uilabel(app.CameraPanel);
            app.xmLabel_2.Text = 'Pixel Size (um)';
            app.pixelSize_EF = uieditfield(app.CameraPanel, 'numeric');
            app.pixelSize_EF.Limits = [0 10];
            app.pixelSize_EF.ValueChangedFcn = createCallbackFcn(app, @pixelSize_EFValueChanged, true);
            app.baseline_Slider = uislider(app.CameraPanel);
            app.baseline_Slider.ValueChangedFcn = createCallbackFcn(app, @baseline_SliderValueChanged, true);
            app.xmLabel_3 = uilabel(app.CameraPanel);
            app.xmLabel_3.Text = 'Baseline (mm)';
            app.baseline_EF = uieditfield(app.CameraPanel, 'numeric');
            app.baseline_EF.Limits = [0 100];
            app.baseline_EF.ValueChangedFcn = createCallbackFcn(app, @baseline_EFValueChanged, true);
            app.CamCol_Slider = uislider(app.CameraPanel);
            app.CamCol_Slider.Limits = [0 3840];
            app.CamCol_Slider.MajorTicks = [];
            app.CamCol_Slider.ValueChangedFcn = createCallbackFcn(app, @CamCol_SliderValueChanged, true);
            app.xmLabel_4 = uilabel(app.CameraPanel);
            app.xmLabel_4.Text = 'Rows x Columns';
            app.CamCol_EF = uieditfield(app.CameraPanel, 'numeric');
            app.CamCol_EF.Limits = [0 3840];
            app.CamCol_EF.ValueChangedFcn = createCallbackFcn(app, @CamCol_EFValueChanged, true);
            app.CamRows_Slider = uislider(app.CameraPanel);
            app.CamRows_Slider.Limits = [0 2160];
            app.CamRows_Slider.ValueChangedFcn = createCallbackFcn(app, @CamRows_SliderValueChanged, true);
            app.CamRows_EF = uieditfield(app.CameraPanel, 'numeric');
            app.CamRows_EF.Limits = [0 2160];
            app.CamRows_EF.ValueChangedFcn = createCallbackFcn(app, @CamRows_EFValueChanged, true);
            app.RunBlenderButton = uibutton(app.UIFigure, 'push');
            app.RunBlenderButton.ButtonPushedFcn = createCallbackFcn(app, @RunBlenderButtonPushed, true);
            app.RunBlenderButton.Text = 'Run Blender';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end