classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProcessingTimesPanel           matlab.ui.container.Panel
        processingTimeycbcrEditField   matlab.ui.control.NumericEditField
        processingTimeycbcrEditFieldLabel  matlab.ui.control.Label
        processingTimeRGBEditField     matlab.ui.control.NumericEditField
        processingTimeRGBEditFieldLabel  matlab.ui.control.Label
        processingTimeBoundingBoxEditField  matlab.ui.control.NumericEditField
        processingTimeBoundingBoxEditFieldLabel  matlab.ui.control.Label
        ycbcrPointsPanel               matlab.ui.container.Panel
        Disparity_ycbcrEditField       matlab.ui.control.NumericEditField
        Disparity_ycbcrEditFieldLabel  matlab.ui.control.Label
        Z_ycbcrEditField               matlab.ui.control.NumericEditField
        Z_ycbcrEditFieldLabel          matlab.ui.control.Label
        Y_ycbcrEditField               matlab.ui.control.NumericEditField
        Y_ycbcrEditFieldLabel          matlab.ui.control.Label
        X_ycbcrEditField               matlab.ui.control.NumericEditField
        X_ycbcrEditFieldLabel          matlab.ui.control.Label
        UITable_3                      matlab.ui.control.Table
        RGBPointsPanel                 matlab.ui.container.Panel
        Disparity_RGBEditField         matlab.ui.control.NumericEditField
        Disparity_RGBEditFieldLabel    matlab.ui.control.Label
        Z_RBGEditField                 matlab.ui.control.NumericEditField
        Z_RBGEditFieldLabel            matlab.ui.control.Label
        Y_RGBEditField                 matlab.ui.control.NumericEditField
        Y_RGBEditFieldLabel            matlab.ui.control.Label
        X_RGBEditField                 matlab.ui.control.NumericEditField
        X_RGBEditFieldLabel            matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        BoundingBoxpointsPanel         matlab.ui.container.Panel
        DisparityEditField             matlab.ui.control.NumericEditField
        DisparityEditFieldLabel        matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ProcessedImagesPanel           matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        OriginalImagesPanel            matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ProcessingTechniquesDropDown   matlab.ui.control.DropDown
        ProcessingTechniquesDropDownLabel  matlab.ui.control.Label
        ProcessButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function ImageProcessFnc(app, event)
            %hard code camera parameters
            ps = 6e-6;
            b = 0.05; % [mm]
            f = 0.006;
          % D. Kaputa
    % rotateCube.m
    % Ravvenlabs

clc;
clear all;
close all;

%Initialization Parameters
server_ip   = '127.0.0.1';     %IP address of the Unity Server
server_port = 55001;           %Server Port of the Unity Sever

client = tcpclient(server_ip,server_port,"Timeout",20);
fprintf(1,"Connected to server\n");

width = 1920;
height = 1080;

for counter = 1:10
    image = blenderLink(client,width,height,1,counter,counter,0,0,0,"tennisBall");
    frames = imagesc(image)
    set(gcf, 'Position', get(0, 'Screensize'));
    axis off
end
            %Read in all images
            %fileNumFix = 5:9; %file names are left5-9 instead of 1-5
            

            leftStereoImages = cell(frames, 1);
            rightStereoImages = cell(frames, 1);


            %------------------------------------------------------------
            
            %does calculations based on drop down value
            switch app.ProcessingTechniquesDropDown.Value
                case 'Bounding Box'
                    %bounding box code
                    %Stores x,y,z values after processing
                    tic;
                    storeValuesBoundingBox = zeros(frames,3);                  
                    %loop through each image
                    for i = 1:frames
                    %Find centeroid for left images
                        %gets image from array
                        indLeftImage = leftStereoImages{i};
                        
                        %Convert to grayscale
                        imageLeftGray = rgb2gray(indLeftImage);

                        %binarize image
                        binaryImageLeft = imbinarize(imageLeftGray);

                        %remove small blobs to try to identify ball
                        removeSmallObjLeft = bwareaopen(binaryImageLeft, 50);

                        %extract values from image
                        leftImageProperties = regionprops(removeSmallObjLeft, 'BoundingBox', 'Centroid', 'Area');
                        %detects any other objects in the photo
                        objects = [leftImageProperties.Area];

                        %Attempt to find  second largest object because the
                        %ball isn't the largest object in the image
                        [~, indexLeft] = sort(objects, 'descend');
                        indLeft = indexLeft(2);

                        %find centeroid
                        centroidLeftImage = leftImageProperties(indLeft).Centroid;

                        %Show image that identifies the ball
                        imshow(indLeftImage, 'Parent', app.UIAxes3);
                        hold(app.UIAxes3, 'on');

                        %draw box
                        rectangle(app.UIAxes3, 'Position', leftImageProperties(indLeft).BoundingBox, 'EdgeColor', 'g', 'LineWidth', 2);
                        plot(app.UIAxes3,centroidLeftImage(1), centroidLeftImage(2), 'r+', 'MarkerSize', 6)
                        hold(app.UIAxes3, 'off');
                        pause(0.5);
                  %---------------------------------------------------------------------------------------------------------------------
                      %Find centeroid for Right images
                         indRightImage = rightStereoImages{i};
                        
                        %Convert to grayscale
                        imageRightGray = rgb2gray(indRightImage);

                        %binarize image
                        binaryImageRight = imbinarize(imageRightGray);

                        %remove small blobs to try to identify ball
                        removeSmallObjRight = bwareaopen(binaryImageRight, 50);

                        %extract values from image
                        RightImageProperties = regionprops(removeSmallObjRight, 'BoundingBox', 'Centroid', 'Area');
                        %detects any other objects in the photo
                        objectsRImage = [RightImageProperties.Area];

                        %Attempt to find  second largest object because the
                        %ball isn't the largest object in the image
                        [~, indexRight] = sort(objectsRImage, 'descend');
                        indRight = indexRight(2);

                        %find centeroid
                        centroidRightImage = RightImageProperties(indRight).Centroid;

                        %Show image that identifies the ball
                        imshow(indRightImage, 'Parent', app.UIAxes4);
                        hold(app.UIAxes4, 'on');

                        %draw box
                        rectangle(app.UIAxes4, 'Position', RightImageProperties(indRight).BoundingBox, 'EdgeColor', 'g', 'LineWidth', 2);
                        %hold(app.UIAxes4, 'off');
                        %pause(0.5);

                        plot(app.UIAxes4,centroidRightImage(1), centroidRightImage(2), 'r+', 'MarkerSize', 6)
                        hold(app.UIAxes4, 'off');
                        pause(0.5);

                        % calculations of X Y Z based on the centroid
                        %uv for center point of ball in the left image
                        uLeft = centroidLeftImage(1);
                        %app.uLeftEditField.Value = uLeft;
                        vLeft = centroidLeftImage(2);
                        
                        %uv for center point of ball in the right image
                        uRight = centroidRightImage(1);
                        %app.uRightEditField.Value = uRight;
                        vRight = centroidRightImage(2);
%use formulas from lab 2
%u is xleft
%v is yleft 
                        %disparity calculation 
                        %first find size of the image
                        [imgHeight, imgWidth, ~] = size(indLeftImage);

                        %get the center of the images
                        cxLeft = imgWidth / 2;
                        cyLeft = imgHeight / 2;
                        cxRight = imgWidth / 2;
                        cyRight = imgHeight / 2;

                        disparity = (abs((uLeft - cxLeft) - (uRight - cxRight)) * ps);
                        app.DisparityEditField.Value = disparity;

                        %calculate Depth
                        Z = (b * f) / disparity;
                        app.ZEditField.Value = Z;

                        %calculate X and Y
                        X = Z * ((uLeft - cxLeft) * ps) / f;
                        app.XEditField.Value = X;
                        Y = Z * ((vLeft - cyLeft) * ps) / f;
                        app.YEditField.Value = Y;
                        
                        %store values of z,y,z in an array
                        storeValuesBoundingBox(i,:) = [X Y Z];

                        %output to table
                        app.UITable.Data = storeValuesBoundingBox;
                        app.UITable.RowName = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4', 'Frame 5'};
                        processingTimeBoundingBox = toc;
                        app.processingTimeBoundingBoxEditField.Value = processingTimeBoundingBox;
                    end
                % case 'RGB'
                %     %RGB code
                %     tic;
                %    storeValuesRGB = zeros(frames,3);
                % 
                %    for i = 1:frames
                %     %load images
                %     leftImages = leftStereoImages{i};
                %     rightImages = rightStereoImages{i};
                % 
                %     %convert to double 
                %     leftImagesDouble = im2double(leftImages);
                %     rightImagesDouble = im2double(rightImages);
                % 
                %     %processing left image
                %     red = leftImagesDouble(:,:,1);
                %     green = leftImagesDouble(:,:,2);
                %     blue = leftImagesDouble(:,:,3);
                % 
                %     %finds average intensity of image
                %     intensityLeftImage = (red + green + blue) / 3;
                % 
                %     %use threshold to keep white pixels
                %     thesholdLeft = intensityLeftImage > 0.75;
                % 
                %     %remove unwanted blobs 
                %     removeBlobs = bwareaopen(thesholdLeft, 100);
                %     fillImage = imfill(removeBlobs, 'holes');
                % 
                %     %get centroid                
                %     LeftImagePropertiesRBG = regionprops(fillImage, intensityLeftImage, 'Centroid', 'MeanIntensity');
                % 
                %      %processing Right image
                %     redR = rightImagesDouble(:,:,1);
                %     greenR = rightImagesDouble(:,:,2);
                %     blueR = rightImagesDouble(:,:,3);
                % 
                %     %finds average intensity of image
                %     intensityRightImage = (redR + greenR + blueR) / 3;
                % 
                %     %use threshold to keep white pixels
                %     thesholdRight = intensityRightImage > 0.80;
                % 
                %     %remove unwanted blobs 
                %     removeBlobsR = bwareaopen(thesholdRight, 100);
                %     fillImageR = imfill(removeBlobsR, 'holes');
                % 
                %     %get centroid                
                %     rightImagePropertiesRBG = regionprops(fillImageR, intensityRightImage, 'Centroid', 'MeanIntensity');
                % 
                %     %find the brightest region because the ball should
                %     %theoretically be the brightest
                % 
                %     [~, indexLeft] = max([LeftImagePropertiesRBG.MeanIntensity]);
                %     [~, indexRight] = max([rightImagePropertiesRBG.MeanIntensity]);
                % 
                %     leftCentroid = LeftImagePropertiesRBG(indexLeft).Centroid;
                %     rightCentroid = rightImagePropertiesRBG(indexRight).Centroid;
                % 
                %     %display processed image on axis
                %     imshow(leftImages, 'Parent', app.UIAxes3);
                %     hold(app.UIAxes3, 'on')
                %     plot(app.UIAxes3, leftCentroid(1), leftCentroid(2),'r+', 'MarkerSize', 6)
                %     hold(app.UIAxes3, 'off')
                %     pause(0.5);
                % 
                %     %right images
                %     imshow(rightImages, 'Parent', app.UIAxes4);
                %     hold(app.UIAxes4, 'on')
                %     plot(app.UIAxes4, rightCentroid(1), rightCentroid(2),'r+', 'MarkerSize', 6)
                %     hold(app.UIAxes4, 'off')
                %     pause(0.5);
                % 
                %     %disparity calculation 
                % 
                %     %uv for center point of ball in the left image
                %     uLeft = leftCentroid(1);
                %     %app.uLeftEditField.Value = uLeft;
                %     vLeft = leftCentroid(2);
                % 
                %     %uv for center point of ball in the right image
                %     uRight = rightCentroid(1);
                %     %app.uRightEditField.Value = uRight;
                %     vRight = rightCentroid(2);
                % 
                %     %first find size of the image
                %     [imgHeight, imgWidth, ~] = size(leftImages);
                % 
                %      %get the center of the images
                %      cxLeft = imgWidth / 2;
                %      cyLeft = imgHeight / 2;
                %      cxRight = imgWidth / 2;
                %      cyRight = imgHeight / 2;
                % 
                %      disparity = (abs((uLeft - cxLeft) - (uRight - cxRight)) * ps);
                %      app.Disparity_RGBEditField.Value = disparity;
                % 
                %      %calculate Depth
                %      Z = (b * f) / disparity;
                %      app.Z_RBGEditField.Value = Z;
                % 
                %      %calculate X and Y
                %      X = Z * ((uLeft - cxLeft) * ps) / f;
                %      app.X_RGBEditField.Value = X;
                %      Y = Z * ((vLeft - cyLeft) * ps) / f;
                %      app.Y_RGBEditField.Value = Y;
                % 
                %      %store values of z,y,z in an array
                %      storeValuesRGB(i,:) = [X Y Z];
                % 
                %      %output to table
                %      app.UITable_2.Data = storeValuesRGB;
                %      app.UITable_2.RowName = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4', 'Frame 5'};
                %      processingTimeRGB = toc;
                %      app.processingTimeRGBEditField.Value = processingTimeRGB;
                %    end
                % case 'ycbcr'
                %     %ycbcr code
                %     tic;
                %     storeValuesYCBCR = zeros(frames,3);
                % 
                %    for i = 1:frames
                %     %load images
                %     leftImages = leftStereoImages{i};
                %     rightImages = rightStereoImages{i};
                % 
                %     %convert to double 
                %     leftImagesDouble = im2double(leftImages);
                %     rightImagesDouble = im2double(rightImages);
                % 
                %     %process left image
                %     leftYcbcr = rgb2ycbcr(leftImagesDouble);
                %     yLeft = leftYcbcr(:,:,1);
                %     cbLeft = leftYcbcr(:,:,2);
                %     crLeft = leftYcbcr(:,:,3);
                % 
                %     %threshold each channel
                %     %Y (0 = black, 1 = white)
                %     %cb (0.5 = neutral(no blue or green bias))
                %     %cr (0.5 = neutral(no red or pink bias))
                % 
                %     thesholdYCBCR = yLeft > 0.7 & abs(cbLeft - 0.5) < 0.12 & abs(crLeft - 0.5) < 0.12;
                % 
                %     %remove unwanted blobs 
                %     removeBlobs = bwareaopen(thesholdYCBCR, 100);
                %     fillImage = imfill(removeBlobs, 'holes');
                % 
                %     %get centroid                
                %     leftImagePropertiesYCBCR = regionprops(fillImage, yLeft, 'Centroid', 'MeanIntensity');
                % 
                %     %find brightest object
                %     [~, indexLeft] = max([leftImagePropertiesYCBCR.MeanIntensity]);
                %     leftCentroid = leftImagePropertiesYCBCR(indexLeft).Centroid;
                % 
                %     %process right image
                %     rightYcbcr = rgb2ycbcr(rightImagesDouble);
                %     yRight = rightYcbcr(:,:,1);
                %     cbRight = rightYcbcr(:,:,2);
                %     crRight = rightYcbcr(:,:,3);
                % 
                %     %threshold each channel
                %     %Y (0 = black, 1 = white)
                %     %cb (0.5 = neutral(no blue or green bias))
                %     %cr (0.5 = neutral(no red or pink bias))
                % 
                %     thesholdYCBCRright = yRight > 0.7 & abs(cbRight - 0.5) < 0.12 & abs(crRight - 0.5) < 0.12;
                % 
                %     %remove unwanted blobs 
                %     removeBlobsRight = bwareaopen(thesholdYCBCRright, 100);
                %     fillImageRight = imfill(removeBlobsRight, 'holes');
                % 
                %     %get centroid                
                %     rightImagePropertiesYCBCR = regionprops(fillImageRight, yRight, 'Centroid', 'MeanIntensity');
                % 
                % 
                %     [~, indexRight] = max([rightImagePropertiesYCBCR.MeanIntensity]);
                %     rightCentroid = rightImagePropertiesYCBCR(indexRight).Centroid;
                % 
                %     %display processed image on axis
                %     imshow(leftImages, 'Parent', app.UIAxes3);
                %     hold(app.UIAxes3, 'on')
                %     plot(app.UIAxes3, leftCentroid(1), leftCentroid(2),'r+', 'MarkerSize', 6)
                %     hold(app.UIAxes3, 'off')
                %     pause(0.5);
                % 
                %     %right images
                %     imshow(rightImages, 'Parent', app.UIAxes4);
                %     hold(app.UIAxes4, 'on')
                %     plot(app.UIAxes4, rightCentroid(1), rightCentroid(2),'r+', 'MarkerSize', 6)
                %     hold(app.UIAxes4, 'off')
                %     pause(0.5);
                % 
                %     %disparity calculation 
                % 
                %     %uv for center point of ball in the left image
                %     uLeft = leftCentroid(1);
                %     %app.uLeftEditField.Value = uLeft;
                %     vLeft = leftCentroid(2);
                % 
                %     %uv for center point of ball in the right image
                %     uRight = rightCentroid(1);
                %     %app.uRightEditField.Value = uRight;
                %     vRight = rightCentroid(2);
                % 
                %     %first find size of the image
                %     [imgHeight, imgWidth, ~] = size(leftImages);
                % 
                %      %get the center of the images
                %      cxLeft = imgWidth / 2;
                %      cyLeft = imgHeight / 2;
                %      cxRight = imgWidth / 2;
                %      cyRight = imgHeight / 2;
                % 
                %      disparity = (abs((uLeft - cxLeft) - (uRight - cxRight)) * ps);
                %      app.Disparity_ycbcrEditField.Value = disparity;
                % 
                %      %calculate Depth
                %      Z = (b * f) / disparity;
                %      app.Z_ycbcrEditField.Value = Z;
                % 
                %      %calculate X and Y
                %      X = Z * ((uLeft - cxLeft) * ps) / f;
                %      app.X_ycbcrEditField.Value = X;
                %      Y = Z * ((vLeft - cyLeft) * ps) / f;
                %      app.Y_ycbcrEditField.Value = Y;
                % 
                %      %store values of z,y,z in an array
                %      storeValuesYCBCR(i,:) = [X Y Z];
                % 
                %      %output to table
                %      app.UITable_3.Data = storeValuesYCBCR;
                %      app.UITable_3.RowName = {'Frame 1', 'Frame 2', 'Frame 3', 'Frame 4', 'Frame 5'};
                %      processingTimeycbcr = toc;
                %      app.processingTimeycbcrEditField.Value = processingTimeycbcr;
                   %end


            end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ImageProcessFnc, true);
            app.ProcessButton.Text = 'Process';
            app.ProcessingTechniquesDropDownLabel = uilabel(app.UIFigure);
            app.ProcessingTechniquesDropDownLabel.Text = 'Processing Techniques';
            app.ProcessingTechniquesDropDown = uidropdown(app.UIFigure);
            app.ProcessingTechniquesDropDown.Items = {'Bounding Box', 'RGB', 'ycbcr'};
            app.ProcessingTechniquesDropDown.Value = 'Bounding Box';
            app.OriginalImagesPanel = uipanel(app.UIFigure);
            app.OriginalImagesPanel.Title = 'Original Images';
            app.UIAxes = uiaxes(app.OriginalImagesPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.OriginalImagesPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ProcessedImagesPanel = uipanel(app.UIFigure);
            app.ProcessedImagesPanel.Title = 'Processed Images';
            app.UIAxes3 = uiaxes(app.ProcessedImagesPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ProcessedImagesPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.BoundingBoxpointsPanel = uipanel(app.UIFigure);
            app.BoundingBoxpointsPanel.Title = 'Bounding Box points ';
            app.UITable = uitable(app.BoundingBoxpointsPanel);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.XEditFieldLabel = uilabel(app.BoundingBoxpointsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.BoundingBoxpointsPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.BoundingBoxpointsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.BoundingBoxpointsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.BoundingBoxpointsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.BoundingBoxpointsPanel, 'numeric');
            app.DisparityEditFieldLabel = uilabel(app.BoundingBoxpointsPanel);
            app.DisparityEditFieldLabel.Text = 'Disparity';
            app.DisparityEditField = uieditfield(app.BoundingBoxpointsPanel, 'numeric');
            app.RGBPointsPanel = uipanel(app.UIFigure);
            app.RGBPointsPanel.Title = 'RGB Points';
            app.UITable_2 = uitable(app.RGBPointsPanel);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable_2.RowName = {};
            app.X_RGBEditFieldLabel = uilabel(app.RGBPointsPanel);
            app.X_RGBEditFieldLabel.Text = 'X_RGB';
            app.X_RGBEditField = uieditfield(app.RGBPointsPanel, 'numeric');
            app.Y_RGBEditFieldLabel = uilabel(app.RGBPointsPanel);
            app.Y_RGBEditFieldLabel.Text = 'Y_RGB';
            app.Y_RGBEditField = uieditfield(app.RGBPointsPanel, 'numeric');
            app.Z_RBGEditFieldLabel = uilabel(app.RGBPointsPanel);
            app.Z_RBGEditFieldLabel.Text = 'Z_RBG';
            app.Z_RBGEditField = uieditfield(app.RGBPointsPanel, 'numeric');
            app.Disparity_RGBEditFieldLabel = uilabel(app.RGBPointsPanel);
            app.Disparity_RGBEditFieldLabel.Text = 'Disparity_RGB';
            app.Disparity_RGBEditField = uieditfield(app.RGBPointsPanel, 'numeric');
            app.ycbcrPointsPanel = uipanel(app.UIFigure);
            app.ycbcrPointsPanel.Title = 'ycbcr Points';
            app.UITable_3 = uitable(app.ycbcrPointsPanel);
            app.UITable_3.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable_3.RowName = {};
            app.X_ycbcrEditFieldLabel = uilabel(app.ycbcrPointsPanel);
            app.X_ycbcrEditFieldLabel.Text = 'X_ycbcr';
            app.X_ycbcrEditField = uieditfield(app.ycbcrPointsPanel, 'numeric');
            app.Y_ycbcrEditFieldLabel = uilabel(app.ycbcrPointsPanel);
            app.Y_ycbcrEditFieldLabel.Text = 'Y_ycbcr';
            app.Y_ycbcrEditField = uieditfield(app.ycbcrPointsPanel, 'numeric');
            app.Z_ycbcrEditFieldLabel = uilabel(app.ycbcrPointsPanel);
            app.Z_ycbcrEditFieldLabel.Text = 'Z_ycbcr';
            app.Z_ycbcrEditField = uieditfield(app.ycbcrPointsPanel, 'numeric');
            app.Disparity_ycbcrEditFieldLabel = uilabel(app.ycbcrPointsPanel);
            app.Disparity_ycbcrEditFieldLabel.Text = 'Disparity_ycbcr';
            app.Disparity_ycbcrEditField = uieditfield(app.ycbcrPointsPanel, 'numeric');
            app.ProcessingTimesPanel = uipanel(app.UIFigure);
            app.ProcessingTimesPanel.Title = 'Processing Times';
            app.processingTimeBoundingBoxEditFieldLabel = uilabel(app.ProcessingTimesPanel);
            app.processingTimeBoundingBoxEditFieldLabel.Text = 'processingTimeBoundingBox';
            app.processingTimeBoundingBoxEditField = uieditfield(app.ProcessingTimesPanel, 'numeric');
            app.processingTimeRGBEditFieldLabel = uilabel(app.ProcessingTimesPanel);
            app.processingTimeRGBEditFieldLabel.Text = 'processingTimeRGB';
            app.processingTimeRGBEditField = uieditfield(app.ProcessingTimesPanel, 'numeric');
            app.processingTimeycbcrEditFieldLabel = uilabel(app.ProcessingTimesPanel);
            app.processingTimeycbcrEditFieldLabel.Text = 'processingTimeycbcr';
            app.processingTimeycbcrEditField = uieditfield(app.ProcessingTimesPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end